classdef filetesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        feaBeam3DLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ProblemNameTextAreaLabel     matlab.ui.control.Label
        ProblemNameTextArea          matlab.ui.control.TextArea
        ExploreButton                matlab.ui.control.Button
        ExploreButton_2              matlab.ui.control.Button
        MeshInputFileTextAreaLabel   matlab.ui.control.Label
        MeshInputFileTextArea        matlab.ui.control.TextArea
        OutputFilenameTextAreaLabel  matlab.ui.control.Label
        OutputFilenameTextArea       matlab.ui.control.TextArea
        OutputTextAreaLabel          matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
        CalculateButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExploreButton
        function ExploreButtonPushed(app, event)
            [file,path] = uigetfile;
            path_str = string(path);
            file_str = string(file);
    
            InputFile = path_str+file_str;
            app.MeshInputFileTextArea.Value = InputFile;
        end

        % Button pushed function: ExploreButton_2
        function ExploreButton_2Pushed(app, event)
            [file,path] = uigetfile;
                     
            OutputFile = string(path);
            app.OutputFilenameTextArea.Value = OutputFile;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.OutputTextArea.Value = ['PROBLEM NAME: ' + string(app.ProblemNameTextArea.Value); 
                                        ' ';
                                       'DISCRETIZATION';
                                        'Mesh Input File: ' + string(app.MeshInputFileTextArea.Value);
                                        'Output File Name: '+ string(app.OutputFilenameTextArea.Value)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.feaBeam3DLabel = uilabel(app.UIFigure);
            app.feaBeam3DLabel.Text = 'feaBeam 3D';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-es-mobile.png';
            app.ProblemNameTextAreaLabel = uilabel(app.UIFigure);
            app.ProblemNameTextAreaLabel.Text = 'Problem Name';
            app.ProblemNameTextArea = uitextarea(app.UIFigure);
            app.ExploreButton = uibutton(app.UIFigure, 'push');
            app.ExploreButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreButtonPushed, true);
            app.ExploreButton.Text = 'Explore';
            app.ExploreButton_2 = uibutton(app.UIFigure, 'push');
            app.ExploreButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExploreButton_2Pushed, true);
            app.ExploreButton_2.Text = 'Explore';
            app.MeshInputFileTextAreaLabel = uilabel(app.UIFigure);
            app.MeshInputFileTextAreaLabel.Text = 'Mesh Input File';
            app.MeshInputFileTextArea = uitextarea(app.UIFigure);
            app.MeshInputFileTextArea.Editable = 'off';
            app.OutputFilenameTextAreaLabel = uilabel(app.UIFigure);
            app.OutputFilenameTextAreaLabel.Text = 'Output Filename';
            app.OutputFilenameTextArea = uitextarea(app.UIFigure);
            app.OutputFilenameTextArea.Editable = 'off';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputTextArea.Editable = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filetesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end